classdef BS2OS < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        OSFigure                    matlab.ui.Figure
        OpeningScreenPanel          matlab.ui.container.Panel
        StartTestButton             matlab.ui.control.Button
        TestTypeDropDownLabel       matlab.ui.control.Label
        TestTypeDropDown            matlab.ui.control.DropDown
        SubjectNoEditFieldLabel     matlab.ui.control.Label
        SubjectNoEditField          matlab.ui.control.NumericEditField
        ASRMagnitudeEditFieldLabel  matlab.ui.control.Label
        ASRMagnitudeEditField       matlab.ui.control.NumericEditField
        TypeofSRDropDownLabel       matlab.ui.control.Label
        TypeofSRDropDown            matlab.ui.control.DropDown
        VSRMagnitudeEditFieldLabel  matlab.ui.control.Label
        VSRMagnitudeEditField       matlab.ui.control.NumericEditField
    end

    
    properties (Access = public)
        DialogApp % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            close all
            clear
            clc
        end

        % Button pushed function: StartTestButton
        function StartTestButtonPushed(app, event)

          
            %Open testing app, subject app, and close OS
            BS2TS(app.TestTypeDropDown.Value, app.SubjectNoEditField.Value, app.TestTypeDropDown.Value, app.ASRMagnitudeEditField.Value, app.VSRMagnitudeEditField.Value)
       
            close(app.OSFigure)
            
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.OSFigure = uifigure('Visible', 'off');
            app.OSFigure.Name = 'UI Figure';
            app.OpeningScreenPanel = uipanel(app.OSFigure);
            app.OpeningScreenPanel.Title = 'Opening Screen';
            app.StartTestButton = uibutton(app.OpeningScreenPanel, 'push');
            app.StartTestButton.ButtonPushedFcn = createCallbackFcn(app, @StartTestButtonPushed, true);
            app.StartTestButton.Text = 'Start Test';
            app.TestTypeDropDownLabel = uilabel(app.OpeningScreenPanel);
            app.TestTypeDropDownLabel.Text = 'Test Type';
            app.TestTypeDropDown = uidropdown(app.OpeningScreenPanel);
            app.TestTypeDropDown.Items = {'Auditory', 'Tactile', 'Visual'};
            app.TestTypeDropDown.Value = 'Auditory';
            app.SubjectNoEditFieldLabel = uilabel(app.OpeningScreenPanel);
            app.SubjectNoEditFieldLabel.Text = 'Subject No.';
            app.SubjectNoEditField = uieditfield(app.OpeningScreenPanel, 'numeric');
            app.ASRMagnitudeEditFieldLabel = uilabel(app.OpeningScreenPanel);
            app.ASRMagnitudeEditFieldLabel.Text = 'ASR Magnitude';
            app.ASRMagnitudeEditField = uieditfield(app.OpeningScreenPanel, 'numeric');
            app.TypeofSRDropDownLabel = uilabel(app.OpeningScreenPanel);
            app.TypeofSRDropDownLabel.Enable = 'off';
            app.TypeofSRDropDownLabel.Text = 'Type of SR';
            app.TypeofSRDropDown = uidropdown(app.OpeningScreenPanel);
            app.TypeofSRDropDown.Items = {'ASR', 'Sham', 'VSR'};
            app.TypeofSRDropDown.Enable = 'off';
            app.TypeofSRDropDown.Value = 'ASR';
            app.VSRMagnitudeEditFieldLabel = uilabel(app.OpeningScreenPanel);
            app.VSRMagnitudeEditFieldLabel.Text = 'VSR Magnitude';
            app.VSRMagnitudeEditField = uieditfield(app.OpeningScreenPanel, 'numeric');
            % Show the figure after all components are created
            app.OSFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BS2OS

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.OSFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.OSFigure)
        end
    end
end