classdef BS2TS < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        TestingScreenPanel     matlab.ui.container.Panel
        AnswerButtonGroup      matlab.ui.container.ButtonGroup
        Presentation1Button    matlab.ui.control.RadioButton
        Presentation2Button    matlab.ui.control.RadioButton
        ConfidenceSliderLabel  matlab.ui.control.Label
        ConfidenceSlider       matlab.ui.control.Slider
        SubmitTrialButton      matlab.ui.control.Button
        BeginNextTrialButton   matlab.ui.control.Button
        Lamp                   matlab.ui.control.Lamp
        STOPTESTButton         matlab.ui.control.Button
        UIAxes                 matlab.ui.control.UIAxes
        CorrectAnswerLabel     matlab.ui.control.Label
        Presentation1Label     matlab.ui.control.Label
        Presentation2Label     matlab.ui.control.Label
        SensoryChannelLabel    matlab.ui.control.Label
        SenseLabel             matlab.ui.control.Label
        SubNumLabel            matlab.ui.control.Label
        nLabel                 matlab.ui.control.Label
        SubjNoLabel            matlab.ui.control.Label
        TrialNoLabel           matlab.ui.control.Label
        StimLabel              matlab.ui.control.Label
        StimDisplayLabel       matlab.ui.control.Label
    end

    
    properties (Access = public)
            n %trial number
            SensoryChannel %which sense is being tested
            correct %correctness vector
            prevStim %previous stimuli info
            curStim %current stiumulus to be tested
            filename %filename for save file
            confidence %how confident they are
            reversals %No. of reversals in current test block
            answer %correct answer for the current trial (trial with stim    
            state %way to pause functions until operator has commanded otherwise
        cha % calls upon CHA hardware
        ASRLevel
        VSRLevel  
        DialogApp % Description
        WriteFile
        response
    end
    
    methods (Access = public)
        
%         function correct = grade(~,subAns,answer,correct,n)
%             
%             %calculate if subject's answer is correct or incorrect
%             g=subAns==answer;
%             
%             %concatenate new grade onto correctness vector
%             correct(app.n)=g;
%         end
        
        function saveData(~,WriteFile,n,stim,response,correct)
            
            %create new row of data that will be saved
            data=[n,abs(stim),response,correct(end)];
            
            %calculate where the new row will be saved in the current file
            row=n+1;
            place=sprintf('A%d:D%d',row,row);
            
            %save new trial data
            xlswrite(WriteFile,data,place)
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, SensoryChannel, SubNum, TypeSR, MagASR, MagVSR)
        app.ASRLevel=MagASR;
        app.VSRLevel=MagVSR;  
        % Open the CHA and play noise if test requires it            
         %  global cha
            % Open the CHA
            if any(MagASR)==1 % only access CHA if noise is needed
            if ~exist('chami', 'class'),
                addpath ..\src
            end
            
            app.cha = chami();
            app.cha.open();
            app.cha.abort_exams(); % In case any were inadvertently left in the queue
          
            
            % Play noise if this is an ASR test
            if any(MagASR) == 1
                noiseLevel=MagASR;
                nf = noise_feature;
                nf.Level = [noiseLevel noiseLevel];
                noise_feature_start(app.cha,nf);
            end
            end
            
           % app.cha=cha;
            app.SensoryChannel=SensoryChannel;
            
            
            % if visual test then change answer titles and 'correct answer' labels to display vert/horiz instead of 1/2


            %create filename to save the file for the data with the naming convention
            %BSRA1_subnum_sensor_SRcond_VSRmuA_ASRdB
            app.filename=sprintf('BSRA1_%03d_%s_%s_%03duA_%03ddB.xlsx',SubNum,SensoryChannel,TypeSR,MagVSR,MagASR);
            app.WriteFile=OpenWriteFile(SubNum,app.filename);
            
            %display current test info
            %app.nLabel.Text=num2str(app.n);
            app.SenseLabel.Text=SensoryChannel;
            app.SubNumLabel.Text=num2str(SubNum);
            
            switch app.SensoryChannel
                case 'Visual'
                    app.Presentation1Button.Text='Presentation 1';
                    app.Presentation2Button.Text='Presentation 2';
                    app.Presentation1Label.Text='Presentation 1';
                    app.Presentation2Label.Text='Presentation 2';
                    wrapper_code_Visual(app);
                case 'Auditory'
                    app.Presentation1Button.Text='Presentation 1';
                    app.Presentation2Button.Text='Presentation 2';
                    app.Presentation1Label.Text='Presentation 1';
                    app.Presentation2Label.Text='Presentation 2';
                    app.DialogApp = BS2SS;
                    wrapper_code_Audio(app);
                case 'Tactile'
                    app.Presentation1Button.Text='Presentation 1';
                    app.Presentation2Button.Text='Presentation 2';
                    app.Presentation1Label.Text='Presentation 1';
                    app.Presentation2Label.Text='Presentation 2';
                    app.DialogApp = BS2SS;
                    wrapper_code_Tactile(app);
               end
            
            % Runs the sensory tests, wrapper code does most of the work
            % for the app
            
            
        end

        % Button pushed function: STOPTESTButton
        function STOPTESTButtonPushed(app, event)
            %save info and throw error, turn off CHA
            if isempty(app.cha)==0
            app.cha.abort_exams();
            end
            %ASRLevel=str2num(app.ASRLevel);
            if any(app.ASRLevel)==1
            noise_feature_stop(app.cha); %stop the noise feature playback
            end
            delete(findall(0)); % closes all UIs
          %  close(app.UIFigure)
          sca;
          close all
          delete(findall(0));
          close(app.UIFigure)
            return
        end

        % Button pushed function: SubmitTrialButton
        function SubmitTrialButtonPushed(app, event)

            
            %get which value the subject answered
            switch app.Presentation1Button.Value
                case 1
                    subAns=1;
                case 0
                    subAns=2;
            end


            %concatenate previous stim level to prevStim vector
            app.prevStim(app.n)=app.curStim;
            
           % saveData(app,app.WriteFile,app.n,app.curStim,app.response,app.correct);
            
            %update plot
            plotpts=abs(app.prevStim);
            stairs(app.UIAxes,[1:app.n],plotpts,'-o');
            
            %make lamp red again for next trial to start
            app.Lamp.Color=[1 0 0];
        end

        % Value changed function: ConfidenceSlider
        function ConfidenceSliderValueChanged(app, event)
            %round confidence slider value to denominations of 5
           
            %get value input to slider 
            value = app.ConfidenceSlider.Value;
           
            %round value to nearest 5
            newVal=round(value/5)*5;
            
            %assign rounded value to slider
            app.ConfidenceSlider.Value=newVal;
        end

        % Button pushed function: BeginNextTrialButton
        function BeginNextTrialButtonPushed(app, event)
            
            uiresume(); % Now commence the test
           
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TestingScreenPanel = uipanel(app.UIFigure);
            app.TestingScreenPanel.Title = 'Testing Screen';
            app.AnswerButtonGroup = uibuttongroup(app.TestingScreenPanel);
            app.AnswerButtonGroup.Title = 'Answer';
            app.Presentation1Button = uiradiobutton(app.AnswerButtonGroup);
            app.Presentation1Button.Text = 'Presentation 1';
            app.Presentation1Button.Value = true;
            app.Presentation2Button = uiradiobutton(app.AnswerButtonGroup);
            app.Presentation2Button.Text = 'Presentation 2';
            app.ConfidenceSliderLabel = uilabel(app.TestingScreenPanel);
            app.ConfidenceSliderLabel.Text = 'Confidence';
            app.ConfidenceSlider = uislider(app.TestingScreenPanel);
            app.ConfidenceSlider.Limits = [50 100];
            app.ConfidenceSlider.ValueChangedFcn = createCallbackFcn(app, @ConfidenceSliderValueChanged, true);
            app.ConfidenceSlider.MinorTicks = [50 55 60 65 70 75 80 85 90 95 100];
            app.ConfidenceSlider.Value = 50;
            app.SubmitTrialButton = uibutton(app.TestingScreenPanel, 'push');
            app.SubmitTrialButton.ButtonPushedFcn = createCallbackFcn(app, @SubmitTrialButtonPushed, true);
            app.SubmitTrialButton.Text = 'Submit Trial';
            app.BeginNextTrialButton = uibutton(app.TestingScreenPanel, 'push');
            app.BeginNextTrialButton.ButtonPushedFcn = createCallbackFcn(app, @BeginNextTrialButtonPushed, true);
            app.BeginNextTrialButton.Text = 'Begin Next Trial';
            app.Lamp = uilamp(app.TestingScreenPanel);
            app.STOPTESTButton = uibutton(app.TestingScreenPanel, 'push');
            app.STOPTESTButton.ButtonPushedFcn = createCallbackFcn(app, @STOPTESTButtonPushed, true);
            app.STOPTESTButton.Text = 'STOP TEST';
            app.UIAxes = uiaxes(app.TestingScreenPanel);
            title(app.UIAxes, 'Staircase')
            xlabel(app.UIAxes, 'Trial')
            ylabel(app.UIAxes, 'Stimulus')
            app.CorrectAnswerLabel = uilabel(app.TestingScreenPanel);
            app.CorrectAnswerLabel.Text = 'Correct Answer';
            app.Presentation1Label = uilabel(app.TestingScreenPanel);
            app.Presentation1Label.Visible = 'off';
            app.Presentation1Label.Text = 'Presentation 1';
            app.Presentation2Label = uilabel(app.TestingScreenPanel);
            app.Presentation2Label.Visible = 'off';
            app.Presentation2Label.Text = 'Presentation 2';
            app.SensoryChannelLabel = uilabel(app.TestingScreenPanel);
            app.SensoryChannelLabel.Text = 'Sensory Channel';
            app.SenseLabel = uilabel(app.TestingScreenPanel);
            app.SenseLabel.Text = 'Sense';
            app.SubNumLabel = uilabel(app.TestingScreenPanel);
            app.SubNumLabel.Text = 'SubNum';
            app.nLabel = uilabel(app.TestingScreenPanel);
            app.nLabel.Text = 'n';
            app.SubjNoLabel = uilabel(app.TestingScreenPanel);
            app.SubjNoLabel.Text = 'Subj. No.';
            app.TrialNoLabel = uilabel(app.TestingScreenPanel);
            app.TrialNoLabel.Text = 'Trial No.';
            app.StimLabel = uilabel(app.TestingScreenPanel);
            app.StimLabel.Text = 'Stim';
            app.StimDisplayLabel = uilabel(app.TestingScreenPanel);
            app.StimDisplayLabel.Text = 'Stim Display';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BS2TS(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end