classdef BS2OS < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        OSFigure                      matlab.ui.Figure
        OpeningScreenPanel            matlab.ui.container.Panel
        TestTypeDropDownLabel         matlab.ui.control.Label
        TestTypeDropDown              matlab.ui.control.DropDown
        SubjectNoEditFieldLabel       matlab.ui.control.Label
        SubjectNoEditField            matlab.ui.control.NumericEditField
        ASRMagnitudeEditFieldLabel    matlab.ui.control.Label
        ASRMagnitudeEditField         matlab.ui.control.NumericEditField
        VSRMagnitudeEditFieldLabel    matlab.ui.control.Label
        VSRMagnitudeEditField         matlab.ui.control.NumericEditField
        SRTypeDropDownLabel           matlab.ui.control.Label
        SRTypeDropDown                matlab.ui.control.DropDown
        NumLabel                      matlab.ui.control.Label
        NumberofTrialsEditFieldLabel  matlab.ui.control.Label
        NumberofTrialsEditField       matlab.ui.control.EditField
        FilenamePanel                 matlab.ui.container.Panel
        CheckFilenameButton           matlab.ui.control.Button
        Lamp                          matlab.ui.control.Lamp
        StartTestButton               matlab.ui.control.Button
    end

    
    properties (Access = public)
        DialogApp % Description
        filename
        WriteFile
        Check
        TotTrialNumber
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            close all
            clear
            clc
            
            cd('C:\Users\BrainStim\research-aim-1\app1buttonSave')
        end

        % Button pushed function: StartTestButton
        function StartTestButtonPushed(app, event)
            
            %make sure admin has selected a sensory channel for testing before
            %running main testing app
            if strcmp(app.TestTypeDropDown.Value,'Select')==1
                error('Please select which sensory channel you will be testing')
            elseif strcmp(app.SRTypeDropDown.Value,'Select')==1
                error('Please select which SR type you will be testing')
            elseif isempty(app.filename)
                error('Need to check filename before beginning test')    
            else
                
                % Create subject's excel spreadsheet
                Trial=0;Stim=0;Response=0;Correct=0;Time=0;
                headers=table(Trial,Stim,Response,Correct,Time);
                writetable(headers,app.WriteFile);
                cd('C:\Users\BrainStim\research-aim-1\app1buttonSave') % go back to previous folder
                
                %Open testing app, subject app, and close OS
                BS2TS(app.TestTypeDropDown.Value, app.SubjectNoEditField.Value, app.TestTypeDropDown.Value, app.ASRMagnitudeEditField.Value, app.VSRMagnitudeEditField.Value,app.filename,app.WriteFile,app.NumberofTrialsEditField.Value)
                system('taskkill /F /IM EXCEL.EXE'); % close open excel files
                close(app.OSFigure)
            end
            
        end

        % Button pushed function: CheckFilenameButton
        function check(app, event)
            if strcmp(app.TestTypeDropDown.Value,'Select')==1
                error('Please select which sensory channel you will be testing')
            elseif strcmp(app.SRTypeDropDown.Value,'Select')==1
                error('Please select which SR type you will be testing')
            end
            
            %create filename to save the file for the data with the naming convention
            %BSRA1_subnum_sensor_SRcond_VSRmuA_ASRdB
            
            ASRround=fix(app.ASRMagnitudeEditField.Value);
            
            if app.ASRMagnitudeEditField.Value>=0
                
                if ASRround~=app.ASRMagnitudeEditField.Value
                    asr=sprintf('%gp%g',ASRround,(app.ASRMagnitudeEditField.Value-ASRround)*10);
                else
                    asr=sprintf('%g',app.ASRMagnitudeEditField.Value);
                end
                
            else
                
                if ASRround~=app.ASRMagnitudeEditField.Value
                    asr=sprintf('neg%gp%g',abs(ASRround),abs(app.ASRMagnitudeEditField.Value-ASRround)*10);
                else
                    asr=sprintf('neg%g',abs(app.ASRMagnitudeEditField.Value));
                end
                
            end
            
            VSRround=floor(app.VSRMagnitudeEditField.Value);
            
            if VSRround~=app.VSRMagnitudeEditField.Value
                vsr=sprintf('%gp%g',VSRround,(app.VSRMagnitudeEditField.Value-VSRround)*10);
            else
                vsr=sprintf('%g',app.VSRMagnitudeEditField.Value);
            end
            
            app.filename=sprintf('BSRA1_%s_%d_%s_%smA_%sdB.xls',app.SRTypeDropDown.Value,app.SubjectNoEditField.Value,app.TestTypeDropDown.Value,vsr,asr);
            
            %folder='C:\Users\BrainStim\research-aim-1\app1buttonSave\RA1Data';
            folder='C:\Users\BrainStim\research-aim-1\app1buttonSave\RA1Data';
            SubjectFolder=fullfile(folder,num2str(app.SubjectNoEditField.Value));
            [~,a]=mkdir(SubjectFolder);
            if ~isempty(a)
                cd(SubjectFolder)
            end

            
            app.Check=0;
            count=1;
            app.Lamp.Color=[0,1,0];
            while app.Check ==0
                if isfile(app.filename)==1
                    app.Lamp.Color=[1,0,0];
                    count=count+1;
                    app.filename=sprintf('BSRA1_%s_%d_%s_%smA_%sdB_%g.xls',app.SRTypeDropDown.Value,app.SubjectNoEditField.Value,app.TestTypeDropDown.Value,vsr,asr,count);
                    app.NumLabel.Text=num2str(count);
                else
                    app.Check=1;
                end
            end
           
            app.WriteFile = fullfile(SubjectFolder,app.filename);
            cd('C:\Users\BrainStim\research-aim-1\app1buttonSave') % go back to previous folder

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.OSFigure = uifigure('Visible', 'off');
            app.OSFigure.Name = 'UI Figure';
            app.OpeningScreenPanel = uipanel(app.OSFigure);
            app.OpeningScreenPanel.Title = 'Opening Screen';
            app.TestTypeDropDownLabel = uilabel(app.OpeningScreenPanel);
            app.TestTypeDropDownLabel.Text = 'Test Type';
            app.TestTypeDropDown = uidropdown(app.OpeningScreenPanel);
            app.TestTypeDropDown.Items = {'Select', 'Auditory', 'Tactile', 'Visual'};
            app.TestTypeDropDown.Value = 'Select';
            app.SubjectNoEditFieldLabel = uilabel(app.OpeningScreenPanel);
            app.SubjectNoEditFieldLabel.Text = 'Subject No.';
            app.SubjectNoEditField = uieditfield(app.OpeningScreenPanel, 'numeric');
            app.ASRMagnitudeEditFieldLabel = uilabel(app.OpeningScreenPanel);
            app.ASRMagnitudeEditFieldLabel.Text = 'ASR Magnitude';
            app.ASRMagnitudeEditField = uieditfield(app.OpeningScreenPanel, 'numeric');
            app.VSRMagnitudeEditFieldLabel = uilabel(app.OpeningScreenPanel);
            app.VSRMagnitudeEditFieldLabel.Text = 'VSR Magnitude';
            app.VSRMagnitudeEditField = uieditfield(app.OpeningScreenPanel, 'numeric');
            app.SRTypeDropDownLabel = uilabel(app.OpeningScreenPanel);
            app.SRTypeDropDownLabel.Text = 'SR Type';
            app.SRTypeDropDown = uidropdown(app.OpeningScreenPanel);
            app.SRTypeDropDown.Items = {'Select', 'ASR', 'VSR', 'Screen'};
            app.SRTypeDropDown.Value = 'Select';
            app.NumLabel = uilabel(app.OpeningScreenPanel);
            app.NumLabel.Text = '';
            app.NumberofTrialsEditFieldLabel = uilabel(app.OpeningScreenPanel);
            app.NumberofTrialsEditFieldLabel.Text = 'Number of Trials';
            app.NumberofTrialsEditField = uieditfield(app.OpeningScreenPanel, 'text');
            app.NumberofTrialsEditField.Value = '0';
            app.FilenamePanel = uipanel(app.OSFigure);
            app.FilenamePanel.Title = 'Filename';
            app.CheckFilenameButton = uibutton(app.FilenamePanel, 'push');
            app.CheckFilenameButton.ButtonPushedFcn = createCallbackFcn(app, @check, true);
            app.CheckFilenameButton.Text = 'Check Filename';
            app.Lamp = uilamp(app.FilenamePanel);
            app.StartTestButton = uibutton(app.OSFigure, 'push');
            app.StartTestButton.ButtonPushedFcn = createCallbackFcn(app, @StartTestButtonPushed, true);
            app.StartTestButton.Text = 'Start Test';
            % Show the figure after all components are created
            app.OSFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BS2OS

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.OSFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.OSFigure)
        end
    end
end