classdef BS2TS < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        TestingScreenPanel     matlab.ui.container.Panel
        AnswerButtonGroup      matlab.ui.container.ButtonGroup
        Presentation1Button    matlab.ui.control.RadioButton
        Presentation2Button    matlab.ui.control.RadioButton
        LapseButton            matlab.ui.control.RadioButton
        SubmitNextTrialButton  matlab.ui.control.Button
        Lamp                   matlab.ui.control.Lamp
        STOPTESTButton         matlab.ui.control.Button
        UIAxes                 matlab.ui.control.UIAxes
        CorrectAnswerLabel     matlab.ui.control.Label
        Presentation1Label     matlab.ui.control.Label
        Presentation2Label     matlab.ui.control.Label
        SensoryChannelLabel    matlab.ui.control.Label
        SenseLabel             matlab.ui.control.Label
        SubNumLabel            matlab.ui.control.Label
        nLabel                 matlab.ui.control.Label
        SubjNoLabel            matlab.ui.control.Label
        TrialNoLabel           matlab.ui.control.Label
        StimLabel              matlab.ui.control.Label
        StimDisplayLabel       matlab.ui.control.Label
    end

    
    properties (Access = public)
            n %trial number
            SensoryChannel %which sense is being tested
            correct %correctness vector
            prevStim %previous stimuli info
            curStim %current stiumulus to be tested
            filename %filename for save file
            reversals %No. of reversals in current test block
            answer %correct answer for the current trial (trial with stim    
            state %way to pause functions until operator has commanded otherwise
            cha % calls upon CHA hardware
            ASRLevel
            VSRLevel  
            DialogApp
            WriteFile
            response
            time
            start
            TotTrialNumber
    end
    
    methods (Access = public)
        
        function saveData(~,WriteFile,n,stim,response,correct,time)
            
            %create new row of data that will be saved
            data=[n,stim,response,correct(end),time];
            
            %calculate where the new row will be saved in the current file
            row=n+1;
            place=sprintf('A%d:E%d',row,row);
            
            %save new trial data
            writematrix(data,WriteFile,'Range',place)
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, SensoryChannel, SubNum, TypeSR, MagASR, MagVSR, filename, WriteFile, TotTrialNumber)
        app.ASRLevel=MagASR;
        app.VSRLevel=MagVSR;
        app.filename=filename;
        app.WriteFile=WriteFile;
        app.TotTrialNumber = TotTrialNumber;
        % Open the CHA and play noise if test requires it            
            % Open the CHA
            if any(MagASR)==1 % only access CHA if noise is needed
            if ~exist('chami', 'class')
                addpath ..\src
            end
            
            app.cha = chami();
            app.cha.open();
            app.cha.abort_exams(); % In case any were inadvertently left in the queue
          
            
            % Play noise if this is an ASR test
            if any(MagASR) == 1
                noiseLevel=MagASR;
                nf = noise_feature;
                nf.Level = [noiseLevel noiseLevel];
                noise_feature_start(app.cha,nf);
            end
            end
            
            app.SensoryChannel=SensoryChannel;
            app.LapseButton.Value=1;                       
            
            
            %display current test info
            app.SenseLabel.Text=SensoryChannel;
            app.SubNumLabel.Text=num2str(SubNum);

                    
            % Runs the sensory tests, wrapper code does most of the work
            % for the app
            switch app.SensoryChannel
                case 'Visual'
                    wrapper_code_Visual(app);
                case 'Auditory'
                    app.DialogApp = BS2SS;
                    wrapper_code_Audio(app);
                case 'Tactile'
                    app.DialogApp = BS2SS;
                    wrapper_code_Tactile(app);
            end
            
        end

        % Button pushed function: STOPTESTButton
        function STOPTESTButtonPushed(app, event)
            %save info and throw error, turn off CHA
            if isempty(app.cha)==0
                app.cha.abort_exams();
            end
            if any(app.ASRLevel)==1
                noise_feature_stop(app.cha); %stop the noise feature playback
            end
            %close visual windows
            sca;
            close all
            delete(findall(0)); % closes all UIs
            % close(app.UIFigure)
            return
        end

        % Button pushed function: SubmitNextTrialButton
        function SubmitNextTrialButtonPushed(app, event)
            
            uiresume(); % Begin next section

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TestingScreenPanel = uipanel(app.UIFigure);
            app.TestingScreenPanel.Title = 'Testing Screen';
            app.AnswerButtonGroup = uibuttongroup(app.TestingScreenPanel);
            app.AnswerButtonGroup.Title = 'Answer';
            app.Presentation1Button = uiradiobutton(app.AnswerButtonGroup);
            app.Presentation1Button.Text = 'Presentation 1';
            app.Presentation1Button.Value = true;
            app.Presentation2Button = uiradiobutton(app.AnswerButtonGroup);
            app.Presentation2Button.Text = 'Presentation 2';
            app.LapseButton = uiradiobutton(app.AnswerButtonGroup);
            app.LapseButton.Text = 'Lapse';
            app.SubmitNextTrialButton = uibutton(app.TestingScreenPanel, 'push');
            app.SubmitNextTrialButton.ButtonPushedFcn = createCallbackFcn(app, @SubmitNextTrialButtonPushed, true);
            app.SubmitNextTrialButton.Text = 'Submit/Next Trial';
            app.Lamp = uilamp(app.TestingScreenPanel);
            app.STOPTESTButton = uibutton(app.TestingScreenPanel, 'push');
            app.STOPTESTButton.ButtonPushedFcn = createCallbackFcn(app, @STOPTESTButtonPushed, true);
            app.STOPTESTButton.Text = 'STOP TEST';
            app.UIAxes = uiaxes(app.TestingScreenPanel);
            title(app.UIAxes, 'Staircase')
            xlabel(app.UIAxes, 'Trial')
            ylabel(app.UIAxes, 'Stimulus')
            app.CorrectAnswerLabel = uilabel(app.TestingScreenPanel);
            app.CorrectAnswerLabel.Text = 'Correct Answer';
            app.Presentation1Label = uilabel(app.TestingScreenPanel);
            app.Presentation1Label.Visible = 'off';
            app.Presentation1Label.Text = 'Presentation 1';
            app.Presentation2Label = uilabel(app.TestingScreenPanel);
            app.Presentation2Label.Visible = 'off';
            app.Presentation2Label.Text = 'Presentation 2';
            app.SensoryChannelLabel = uilabel(app.TestingScreenPanel);
            app.SensoryChannelLabel.Text = 'Sensory Channel';
            app.SenseLabel = uilabel(app.TestingScreenPanel);
            app.SenseLabel.Text = 'Sense';
            app.SubNumLabel = uilabel(app.TestingScreenPanel);
            app.SubNumLabel.Text = 'SubNum';
            app.nLabel = uilabel(app.TestingScreenPanel);
            app.nLabel.Text = 'n';
            app.SubjNoLabel = uilabel(app.TestingScreenPanel);
            app.SubjNoLabel.Text = 'Subj. No.';
            app.TrialNoLabel = uilabel(app.TestingScreenPanel);
            app.TrialNoLabel.Text = 'Trial No.';
            app.StimLabel = uilabel(app.TestingScreenPanel);
            app.StimLabel.Text = 'Stim';
            app.StimDisplayLabel = uilabel(app.TestingScreenPanel);
            app.StimDisplayLabel.Text = 'Stim Display';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BS2TS(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end